classdef Face_recognition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TextArea_6               matlab.ui.control.TextArea
        TextArea_5               matlab.ui.control.TextArea
        TextArea_4               matlab.ui.control.TextArea
        TextArea_3               matlab.ui.control.TextArea
        TextArea_2               matlab.ui.control.TextArea
        TextArea_1               matlab.ui.control.TextArea
        PictureDropDown          matlab.ui.control.DropDown
        PictureDropDownLabel     matlab.ui.control.Label
        Image6                   matlab.ui.control.Image
        Image5                   matlab.ui.control.Image
        Image4                   matlab.ui.control.Image
        Image3                   matlab.ui.control.Image
        Image2                   matlab.ui.control.Image
        Image1                   matlab.ui.control.Image
        FacialRecognitionButton  matlab.ui.control.Button
        TextArea                 matlab.ui.control.TextArea
        CheckBox                 matlab.ui.control.CheckBox
        NewUserButton            matlab.ui.control.StateButton
        NameEditField            matlab.ui.control.EditField
        NameEditFieldLabel       matlab.ui.control.Label
        Image                    matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NewUserButton
        function NewUserButtonValueChanged(app, event)

            %while loop to prompt the user to enter their name 
            while app.NewUserButton.Value == 1
                app.TextArea.Value = {'Record your name'; 'When ready, click the checkbox'};

                if app.CheckBox.Value == 1

                    name = app.NameEditField.Value;

                    %create a new folder in the database corresponding to
                    %the new user's name
                    newfolder = string(name);
                    mkdir('Database\', newfolder);
                    
                    %set the button back to zero to move on in the function
                    app.NewUserButton.Value = 0;

                end
                pause(1);
            end
            
            i = 1;
            while i <= 6
                
                %create webcam
                cam = webcam; %change webcam object here

                preview(cam);

                %initialize face detector object
                %set minimum size to reduce detector picking up background objects
                detector = vision.CascadeObjectDetector('MinSize', [50 50]);
                
                %countdown until photo will be taken 
                for j = 1:3
                    count = 4-j;
                    str = sprintf('Your photo will be taken in %d seconds', count);
                    app.TextArea.Value = str;
                    pause(1);
                end
                
                %take snapshot
                img = snapshot(cam);
                
                %close the preview and print a message so the user knows their photo was taken
                clear cam;

                %detect a face within the snapshot
                bbox = detector(img);
                
                %check to see if a face was recognized
                if isempty(bbox) == 1
                    
                    %print that no face was detected
                    %dont update i
                    app.TextArea.Value = {'No face detected'; 'Retaking photo'};

                    pause(2);

                else 

                    %print that the photo was taken successfully
                    count = 6 - i;
                    str = sprintf('Your photo was taken succesfully, %d photos left', count);
                    app.TextArea.Value = str;
                    
                    pause(1);


                    %crop image to dimensions specified by the face detector
                    img_crop = imcrop(img, [bbox(1,:)]);

                    %convert image to grey scale
                    img_grey = rgb2gray(img_crop);

                    %resize image
                    img_resize = imresize(img_grey, [300 300]);


                    %Save the image in the correct folder
                    file_path = sprintf('Database\\%s\\%d.jpg', name, i);
                    imwrite(img_resize, file_path);


                    %set display image to the photo taken
                    if i == 1
                        app.Image1.ImageSource = file_path;
                    elseif i == 2
                        app.Image2.ImageSource = file_path;
                    elseif i == 3
                        app.Image3.ImageSource = file_path;
                    elseif i == 4
                        app.Image4.ImageSource = file_path;
                    elseif i == 5
                        app.Image5.ImageSource = file_path;
                    elseif i == 6
                        app.Image6.ImageSource = file_path;
                    end

                    %update i
                    i = i+1;
                end


            end


            %clean up
            clear cam;
            release(detector);

            str = sprintf('New user - %s - registered successfully.\n\nIf a photo does not contain your face, retake it by selecting it from the drop down menu', name);
            app.TextArea.Value = str;
            app.CheckBox.Value = 0;

        end

        % Value changed function: PictureDropDown
        function PictureDropDownValueChanged(app, event)

            name = app.NameEditField.Value;
            
            %Retake a photo if the dropdown menu is not set to none
            if app.PictureDropDown.Value ~= "None"

                %create webcam
                cam = webcam; %change webcam object here

                preview(cam);

                %initialize face detector object
                %set minimum size to reduce detector picking up background objects
                detector = vision.CascadeObjectDetector('MinSize', [50 50]);

                %countdown until photo will be taken 
                for j = 1:3
                    count = 4-j;
                    str = sprintf('Your photo will be taken in %d seconds', count);
                    app.TextArea.Value = str;
                    pause(1);
                end
                
                %take snapshot
                img = snapshot(cam);

                %close the preview so the user knows their photo was taken
                clear cam;
                
                %detect a face within the snapshot
                bbox = detector(img);
                
                %check to see if a face was recognized
                if isempty(bbox) == 1
                    
                    %print that no face was detected
                    app.TextArea.Value = {'No face detected';'Reselect the image number from the dropdown menu to try again'};

                else
                    app.TextArea.Value = 'Your photo was taken succesfully';

                    pause(1);


                    %crop image to dimensions found by face detector
                    img_crop = imcrop(img, [bbox(1,:)]);

                    %convert image to grey scale
                    img_grey = rgb2gray(img_crop);

                    %resize image
                    img_resize = imresize(img_grey, [300 300]);

                    %Overwrite the correct picture
                    %write the photo in the correct file path as well as with a
                    %random image number to circumvent MATLAB optimization

                    %generate random integer from 1 to 1000 (provides a 0.1%
                    %chance of repeating
                    r = randi([1,1000],1,1);
                    r_path = sprintf('Image\\%d.jpg', r);

                    if app.PictureDropDown.Value == "1"

                        %Write image to two files
                        file_path = sprintf('Database\\%s\\1.jpg', name);
                        imwrite(img_resize, file_path);
                        imwrite(img_resize, r_path);

                        %Upload using randomized file
                        app.Image1.ImageSource = r_path;

                    elseif app.PictureDropDown.Value == "2"

                        %Write image to two files
                        file_path = sprintf('Database\\%s\\2.jpg', name);
                        imwrite(img_resize, file_path);
                        imwrite(img_resize, r_path);

                        %Upload using randomized file
                        app.Image2.ImageSource = r_path;

                    elseif app.PictureDropDown.Value == "3"

                        %Write image to two files
                        file_path = sprintf('Database\\%s\\3.jpg', name);
                        imwrite(img_resize, file_path);
                        imwrite(img_resize, r_path);

                        %Upload using randomized file
                        app.Image3.ImageSource = r_path;

                    elseif app.PictureDropDown.Value == "4"

                        %Write image to two files
                        file_path = sprintf('Database\\%s\\4.jpg', name);
                        imwrite(img_resize, file_path);
                        imwrite(img_resize, r_path);

                        %Upload using randomized file
                        app.Image4.ImageSource = r_path;

                    elseif app.PictureDropDown.Value == "5"

                        %Write image to two files
                        file_path = sprintf('Database\\%s\\5.jpg', name);
                        imwrite(img_resize, file_path);
                        imwrite(img_resize, r_path);

                        %Upload using randomized file
                        app.Image5.ImageSource = r_path;

                    elseif app.PictureDropDown.Value == "6"

                        %Write image to two files
                        file_path = sprintf('Database\\%s\\6.jpg', name);
                        imwrite(img_resize, file_path);
                        imwrite(img_resize, r_path);

                        %Upload using randomized file
                        app.Image6.ImageSource = r_path;

                    end
                    app.TextArea.Value = {'Image uploaded succesfully'; 'To change another image select it from the dropdown menu'};
                end
            end

            %clean up
            clear cam;
            release(detector);

            %reset the drop down menu to none
            app.PictureDropDown.Value = "None";


        end

        % Button pushed function: FacialRecognitionButton
        function FacialRecognitionButtonPushed(app, event)
            
            %load images from the database 
            faceDataBase = imageDatastore('Database', "IncludeSubfolders", true,  "LabelSource", "foldernames");

            %determine the total number of images (assuming 6 per user)
            T = countEachLabel(faceDataBase);
            x = size(T);
            numImages = x(1,1)*6;

            %read a single image to get the length for the HOG extraction
            hog = extractHOGFeatures(readimage(faceDataBase,1));
            hogFeatureSize = length(hog);

            %create an empty array to store the facial feature
            facialFeatures = zeros(numImages, hogFeatureSize, 'single');

            %extract HOGfeatures from each image
            for i = 1:numImages

                img = readimage(faceDataBase,i);
                facialFeatures(i, :) = extractHOGFeatures(img);

            end

            %create a variable storing each label
            labels = faceDataBase.Labels;

            %create the model using fitcecoc
            classifier = fitcecoc(facialFeatures, labels);

            % Create the webcam object.
            cam = webcam; %change webcam object here

            preview(cam);

            pause (3);

            %initialize face detector
            %set minimum size to reduce detector picking up background objects
            detector = vision.CascadeObjectDetector('MinSize', [50 50]);

            %take a snapshot
            img = snapshot(cam);

            %clear cam after taking snapshot
            clear cam;

            %Detect a face within the snapshot
            bbox = detector(img);
            
            %check to see if a face was detected
            if isempty(bbox) == 1

                %print that no face was detected
                app.TextArea.Value = {'No face detected';'Click Facial Recognition to try again'};

            else

                %crop image to dimensions as specificed by face detector
                img_crop = imcrop(img, [bbox(1,:)]);

                %upload cropped image to the GUI using a randomized file path
                r = randi([1,1000],1,1);
                r_path = sprintf('Image\\%d.jpg', r);
                imwrite(img_crop, r_path);
                app.Image.ImageSource = r_path;

                %convert image to grey scale
                img_grey = rgb2gray(img_crop);

                %resize image
                img_resize = imresize(img_grey, [300 300]);

                %extract features from the resized image
                hogFeatures = extractHOGFeatures(img_resize);

                %try to match the face to the database
                [predictFace, score] = predict(classifier, hogFeatures);

                %Determine if a user was found
                if max(score) < -0.3 %update error tolerance here

                    app.TextArea.Value = 'No user found, please try again';

                else
                    %if a user is found print out "Hello User"

                    %convert predictFace (catergorical variable) to a string
                    user = string(predictFace(1,1));

                    %print
                    str = sprintf('Hello %s', user);
                    app.TextArea.Value = str;


                    %Upload the database images to the photos on the right
                    file_path = sprintf('Database\\%s\\1.jpg', user);
                    app.Image1.ImageSource = file_path;
                    file_path = sprintf('Database\\%s\\2.jpg', user);
                    app.Image2.ImageSource = file_path;
                    file_path = sprintf('Database\\%s\\3.jpg', user);
                    app.Image3.ImageSource = file_path;
                    file_path = sprintf('Database\\%s\\4.jpg', user);
                    app.Image4.ImageSource = file_path;
                    file_path = sprintf('Database\\%s\\5.jpg', user);
                    app.Image5.ImageSource = file_path;
                    file_path = sprintf('Database\\%s\\6.jpg', user);
                    app.Image6.ImageSource = file_path;

                end
            end

            %clean up
            clear cam;
            release(detector);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.NewUserButton = uibutton(app.UIFigure, 'state');
            app.NewUserButton.ValueChangedFcn = createCallbackFcn(app, @NewUserButtonValueChanged, true);
            app.NewUserButton.Text = 'New User';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.FacialRecognitionButton = uibutton(app.UIFigure, 'push');
            app.FacialRecognitionButton.ButtonPushedFcn = createCallbackFcn(app, @FacialRecognitionButtonPushed, true);
            app.FacialRecognitionButton.Text = 'Facial Recognition';
            app.Image1 = uiimage(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            app.Image3 = uiimage(app.UIFigure);
            app.Image4 = uiimage(app.UIFigure);
            app.Image5 = uiimage(app.UIFigure);
            app.Image6 = uiimage(app.UIFigure);
            app.PictureDropDownLabel = uilabel(app.UIFigure);
            app.PictureDropDownLabel.Text = 'Picture #';
            app.PictureDropDown = uidropdown(app.UIFigure);
            app.PictureDropDown.Items = {'None', '1', '2', '3', '4', '5', '6'};
            app.PictureDropDown.ValueChangedFcn = createCallbackFcn(app, @PictureDropDownValueChanged, true);
            app.PictureDropDown.Value = 'None';
            app.TextArea_1 = uitextarea(app.UIFigure);
            app.TextArea_1.Value = {'1'};
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'2'};
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.Value = {'3'};
            app.TextArea_4 = uitextarea(app.UIFigure);
            app.TextArea_4.Value = {'4'};
            app.TextArea_5 = uitextarea(app.UIFigure);
            app.TextArea_5.Value = {'5'};
            app.TextArea_6 = uitextarea(app.UIFigure);
            app.TextArea_6.Value = {'6'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Face_recognition

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end